classdef cytosolQuant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cytosolQuant2022UIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        NTimeSeriesLabel                matlab.ui.control.Label
        TS_Tab                          matlab.ui.control.NumericEditField
        ChannelALabel                   matlab.ui.control.Label
        ChA_Tab                         matlab.ui.control.DropDown
        ExperimentIDLabel               matlab.ui.control.Label
        experimentID_Tab                matlab.ui.control.EditField
        activateTab                     matlab.ui.control.CheckBox
        xDimEditFieldLabel              matlab.ui.control.Label
        xDim_Tab                        matlab.ui.control.NumericEditField
        ImagepropertiesLabel            matlab.ui.control.Label
        yDimEditFieldLabel              matlab.ui.control.Label
        yDim_Tab                        matlab.ui.control.NumericEditField
        zDimEditFieldLabel              matlab.ui.control.Label
        zDim_Tab                        matlab.ui.control.NumericEditField
        includeTS_group_Tab             matlab.ui.container.ButtonGroup
        includeTS_all_Tab               matlab.ui.control.RadioButton
        includeTS_selected_Tab          matlab.ui.control.RadioButton
        ChannelBLabel                   matlab.ui.control.Label
        ChB_Tab                         matlab.ui.control.DropDown
        ChannelCLabel                   matlab.ui.control.Label
        ChC_Tab                         matlab.ui.control.DropDown
        ControlpositionsPanel           matlab.ui.container.Panel
        exp1_pos1                       matlab.ui.control.CheckBox
        exp1_pos2                       matlab.ui.control.CheckBox
        exp1_pos3                       matlab.ui.control.CheckBox
        exp1_pos4                       matlab.ui.control.CheckBox
        exp1_pos5                       matlab.ui.control.CheckBox
        exp1_pos6                       matlab.ui.control.CheckBox
        exp1_pos7                       matlab.ui.control.CheckBox
        exp1_pos8                       matlab.ui.control.CheckBox
        exp1_pos9                       matlab.ui.control.CheckBox
        exp1_pos10                      matlab.ui.control.CheckBox
        Tab_2                           matlab.ui.container.Tab
        NTimeSeriesLabel_2              matlab.ui.control.Label
        TS_Tab_2                        matlab.ui.control.NumericEditField
        ChannelALabel_2                 matlab.ui.control.Label
        ChA_Tab_2                       matlab.ui.control.DropDown
        ExperimentIDLabel_2             matlab.ui.control.Label
        experimentID_Tab_2              matlab.ui.control.EditField
        activateTab_2                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_2            matlab.ui.control.Label
        xDim_Tab_2                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_2          matlab.ui.control.Label
        yDimEditFieldLabel_2            matlab.ui.control.Label
        yDim_Tab_2                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_2            matlab.ui.control.Label
        zDim_Tab_2                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_2           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_2             matlab.ui.control.RadioButton
        includeTS_selected_Tab_2        matlab.ui.control.RadioButton
        ChannelBLabel_2                 matlab.ui.control.Label
        ChB_Tab_2                       matlab.ui.control.DropDown
        ChannelCLabel_2                 matlab.ui.control.Label
        ChC_Tab_2                       matlab.ui.control.DropDown
        ControlpositionsPanel_2         matlab.ui.container.Panel
        exp2_pos1                       matlab.ui.control.CheckBox
        exp2_pos2                       matlab.ui.control.CheckBox
        exp2_pos3                       matlab.ui.control.CheckBox
        exp2_pos4                       matlab.ui.control.CheckBox
        exp2_pos5                       matlab.ui.control.CheckBox
        exp2_pos6                       matlab.ui.control.CheckBox
        exp2_pos7                       matlab.ui.control.CheckBox
        exp2_pos8                       matlab.ui.control.CheckBox
        exp2_pos9                       matlab.ui.control.CheckBox
        exp2_pos10                      matlab.ui.control.CheckBox
        Tab_3                           matlab.ui.container.Tab
        NTimeSeriesLabel_3              matlab.ui.control.Label
        TS_Tab_3                        matlab.ui.control.NumericEditField
        ChannelALabel_3                 matlab.ui.control.Label
        ChA_Tab_3                       matlab.ui.control.DropDown
        ExperimentIDLabel_3             matlab.ui.control.Label
        experimentID_Tab_3              matlab.ui.control.EditField
        activateTab_3                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_3            matlab.ui.control.Label
        xDim_Tab_3                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_3          matlab.ui.control.Label
        yDimEditFieldLabel_3            matlab.ui.control.Label
        yDim_Tab_3                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_3            matlab.ui.control.Label
        zDim_Tab_3                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_3           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_3             matlab.ui.control.RadioButton
        includeTS_selected_Tab_3        matlab.ui.control.RadioButton
        ChannelBLabel_3                 matlab.ui.control.Label
        ChB_Tab_3                       matlab.ui.control.DropDown
        ChannelCLabel_3                 matlab.ui.control.Label
        ChC_Tab_3                       matlab.ui.control.DropDown
        ControlpositionsPanel_3         matlab.ui.container.Panel
        exp3_pos1                       matlab.ui.control.CheckBox
        exp3_pos2                       matlab.ui.control.CheckBox
        exp3_pos3                       matlab.ui.control.CheckBox
        exp3_pos4                       matlab.ui.control.CheckBox
        exp3_pos5                       matlab.ui.control.CheckBox
        exp3_pos6                       matlab.ui.control.CheckBox
        exp3_pos7                       matlab.ui.control.CheckBox
        exp3_pos8                       matlab.ui.control.CheckBox
        exp3_pos9                       matlab.ui.control.CheckBox
        exp3_pos10                      matlab.ui.control.CheckBox
        Tab_4                           matlab.ui.container.Tab
        NTimeSeriesLabel_4              matlab.ui.control.Label
        TS_Tab_4                        matlab.ui.control.NumericEditField
        ChannelALabel_4                 matlab.ui.control.Label
        ChA_Tab_4                       matlab.ui.control.DropDown
        ExperimentIDLabel_4             matlab.ui.control.Label
        experimentID_Tab_4              matlab.ui.control.EditField
        activateTab_4                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_4            matlab.ui.control.Label
        xDim_Tab_4                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_4          matlab.ui.control.Label
        yDimEditFieldLabel_4            matlab.ui.control.Label
        yDim_Tab_4                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_4            matlab.ui.control.Label
        zDim_Tab_4                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_4           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_4             matlab.ui.control.RadioButton
        includeTS_selected_Tab_4        matlab.ui.control.RadioButton
        ChannelBLabel_4                 matlab.ui.control.Label
        ChB_Tab_4                       matlab.ui.control.DropDown
        ChannelCLabel_4                 matlab.ui.control.Label
        ChC_Tab_4                       matlab.ui.control.DropDown
        ControlpositionsPanel_4         matlab.ui.container.Panel
        exp4_pos1                       matlab.ui.control.CheckBox
        exp4_pos2                       matlab.ui.control.CheckBox
        exp4_pos3                       matlab.ui.control.CheckBox
        exp4_pos4                       matlab.ui.control.CheckBox
        exp4_pos5                       matlab.ui.control.CheckBox
        exp4_pos6                       matlab.ui.control.CheckBox
        exp4_pos7                       matlab.ui.control.CheckBox
        exp4_pos8                       matlab.ui.control.CheckBox
        exp4_pos9                       matlab.ui.control.CheckBox
        exp4_pos10                      matlab.ui.control.CheckBox
        Tab_5                           matlab.ui.container.Tab
        NTimeSeriesLabel_5              matlab.ui.control.Label
        TS_Tab_5                        matlab.ui.control.NumericEditField
        ChannelALabel_5                 matlab.ui.control.Label
        ChA_Tab_5                       matlab.ui.control.DropDown
        ExperimentIDLabel_5             matlab.ui.control.Label
        experimentID_Tab_5              matlab.ui.control.EditField
        activateTab_5                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_5            matlab.ui.control.Label
        xDim_Tab_5                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_5          matlab.ui.control.Label
        yDimEditFieldLabel_5            matlab.ui.control.Label
        yDim_Tab_5                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_5            matlab.ui.control.Label
        zDim_Tab_5                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_5           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_5             matlab.ui.control.RadioButton
        includeTS_selected_Tab_5        matlab.ui.control.RadioButton
        ChannelBLabel_5                 matlab.ui.control.Label
        ChB_Tab_5                       matlab.ui.control.DropDown
        ChannelCLabel_5                 matlab.ui.control.Label
        ChC_Tab_5                       matlab.ui.control.DropDown
        ControlpositionsPanel_5         matlab.ui.container.Panel
        exp5_pos1                       matlab.ui.control.CheckBox
        exp5_pos2                       matlab.ui.control.CheckBox
        exp5_pos3                       matlab.ui.control.CheckBox
        exp5_pos4                       matlab.ui.control.CheckBox
        exp5_pos5                       matlab.ui.control.CheckBox
        exp5_pos6                       matlab.ui.control.CheckBox
        exp5_pos7                       matlab.ui.control.CheckBox
        exp5_pos8                       matlab.ui.control.CheckBox
        exp5_pos9                       matlab.ui.control.CheckBox
        exp5_pos10                      matlab.ui.control.CheckBox
        Tab_6                           matlab.ui.container.Tab
        NTimeSeriesLabel_6              matlab.ui.control.Label
        TS_Tab_6                        matlab.ui.control.NumericEditField
        ChannelALabel_6                 matlab.ui.control.Label
        ChA_Tab_6                       matlab.ui.control.DropDown
        ExperimentIDLabel_6             matlab.ui.control.Label
        experimentID_Tab_6              matlab.ui.control.EditField
        activateTab_6                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_6            matlab.ui.control.Label
        xDim_Tab_6                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_6          matlab.ui.control.Label
        yDimEditFieldLabel_6            matlab.ui.control.Label
        yDim_Tab_6                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_6            matlab.ui.control.Label
        zDim_Tab_6                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_6           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_6             matlab.ui.control.RadioButton
        includeTS_selected_Tab_6        matlab.ui.control.RadioButton
        ChannelBLabel_6                 matlab.ui.control.Label
        ChB_Tab_6                       matlab.ui.control.DropDown
        ChannelCLabel_6                 matlab.ui.control.Label
        ChC_Tab_6                       matlab.ui.control.DropDown
        ControlpositionsPanel_6         matlab.ui.container.Panel
        exp6_pos1                       matlab.ui.control.CheckBox
        exp6_pos2                       matlab.ui.control.CheckBox
        exp6_pos3                       matlab.ui.control.CheckBox
        exp6_pos4                       matlab.ui.control.CheckBox
        exp6_pos5                       matlab.ui.control.CheckBox
        exp6_pos6                       matlab.ui.control.CheckBox
        exp6_pos7                       matlab.ui.control.CheckBox
        exp6_pos8                       matlab.ui.control.CheckBox
        exp6_pos9                       matlab.ui.control.CheckBox
        exp6_pos10                      matlab.ui.control.CheckBox
        Tab_7                           matlab.ui.container.Tab
        NTimeSeriesLabel_7              matlab.ui.control.Label
        TS_Tab_7                        matlab.ui.control.NumericEditField
        ChannelALabel_7                 matlab.ui.control.Label
        ChA_Tab_7                       matlab.ui.control.DropDown
        ExperimentIDLabel_7             matlab.ui.control.Label
        experimentID_Tab_7              matlab.ui.control.EditField
        activateTab_7                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_7            matlab.ui.control.Label
        xDim_Tab_7                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_7          matlab.ui.control.Label
        yDimEditFieldLabel_7            matlab.ui.control.Label
        yDim_Tab_7                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_7            matlab.ui.control.Label
        zDim_Tab_7                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_7           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_7             matlab.ui.control.RadioButton
        includeTS_selected_Tab_7        matlab.ui.control.RadioButton
        ChannelBLabel_7                 matlab.ui.control.Label
        ChB_Tab_7                       matlab.ui.control.DropDown
        ChannelCLabel_7                 matlab.ui.control.Label
        ChC_Tab_7                       matlab.ui.control.DropDown
        ControlpositionsPanel_7         matlab.ui.container.Panel
        exp7_pos1                       matlab.ui.control.CheckBox
        exp7_pos2                       matlab.ui.control.CheckBox
        exp7_pos3                       matlab.ui.control.CheckBox
        exp7_pos4                       matlab.ui.control.CheckBox
        exp7_pos5                       matlab.ui.control.CheckBox
        exp7_pos6                       matlab.ui.control.CheckBox
        exp7_pos7                       matlab.ui.control.CheckBox
        exp7_pos8                       matlab.ui.control.CheckBox
        exp7_pos9                       matlab.ui.control.CheckBox
        exp7_pos10                      matlab.ui.control.CheckBox
        Tab_8                           matlab.ui.container.Tab
        NTimeSeriesLabel_8              matlab.ui.control.Label
        TS_Tab_8                        matlab.ui.control.NumericEditField
        ChannelALabel_8                 matlab.ui.control.Label
        ChA_Tab_8                       matlab.ui.control.DropDown
        ExperimentIDLabel_8             matlab.ui.control.Label
        experimentID_Tab_8              matlab.ui.control.EditField
        activateTab_8                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_8            matlab.ui.control.Label
        xDim_Tab_8                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_8          matlab.ui.control.Label
        yDimEditFieldLabel_8            matlab.ui.control.Label
        yDim_Tab_8                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_8            matlab.ui.control.Label
        zDim_Tab_8                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_8           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_8             matlab.ui.control.RadioButton
        includeTS_selected_Tab_8        matlab.ui.control.RadioButton
        ChannelBLabel_8                 matlab.ui.control.Label
        ChB_Tab_8                       matlab.ui.control.DropDown
        ChannelCLabel_8                 matlab.ui.control.Label
        ChC_Tab_8                       matlab.ui.control.DropDown
        ControlpositionsPanel_8         matlab.ui.container.Panel
        exp8_pos1                       matlab.ui.control.CheckBox
        exp8_pos2                       matlab.ui.control.CheckBox
        exp8_pos3                       matlab.ui.control.CheckBox
        exp8_pos4                       matlab.ui.control.CheckBox
        exp8_pos5                       matlab.ui.control.CheckBox
        exp8_pos6                       matlab.ui.control.CheckBox
        exp8_pos7                       matlab.ui.control.CheckBox
        exp8_pos8                       matlab.ui.control.CheckBox
        exp8_pos9                       matlab.ui.control.CheckBox
        exp8_pos10                      matlab.ui.control.CheckBox
        Tab_9                           matlab.ui.container.Tab
        NTimeSeriesLabel_9              matlab.ui.control.Label
        TS_Tab_9                        matlab.ui.control.NumericEditField
        ChannelALabel_9                 matlab.ui.control.Label
        ChA_Tab_9                       matlab.ui.control.DropDown
        ExperimentIDLabel_9             matlab.ui.control.Label
        experimentID_Tab_9              matlab.ui.control.EditField
        activateTab_9                   matlab.ui.control.CheckBox
        xDimEditFieldLabel_9            matlab.ui.control.Label
        xDim_Tab_9                      matlab.ui.control.NumericEditField
        ImagepropertiesLabel_9          matlab.ui.control.Label
        yDimEditFieldLabel_9            matlab.ui.control.Label
        yDim_Tab_9                      matlab.ui.control.NumericEditField
        zDimEditFieldLabel_9            matlab.ui.control.Label
        zDim_Tab_9                      matlab.ui.control.NumericEditField
        includeTS_group_Tab_9           matlab.ui.container.ButtonGroup
        includeTS_all_Tab_9             matlab.ui.control.RadioButton
        includeTS_selected_Tab_9        matlab.ui.control.RadioButton
        ChannelBLabel_9                 matlab.ui.control.Label
        ChB_Tab_9                       matlab.ui.control.DropDown
        ChannelCLabel_9                 matlab.ui.control.Label
        ChC_Tab_9                       matlab.ui.control.DropDown
        ControlpositionsPanel_9         matlab.ui.container.Panel
        exp9_pos1                       matlab.ui.control.CheckBox
        exp9_pos2                       matlab.ui.control.CheckBox
        exp9_pos3                       matlab.ui.control.CheckBox
        exp9_pos4                       matlab.ui.control.CheckBox
        exp9_pos5                       matlab.ui.control.CheckBox
        exp9_pos6                       matlab.ui.control.CheckBox
        exp9_pos7                       matlab.ui.control.CheckBox
        exp9_pos8                       matlab.ui.control.CheckBox
        exp9_pos9                       matlab.ui.control.CheckBox
        exp9_pos10                      matlab.ui.control.CheckBox
        Tab_10                          matlab.ui.container.Tab
        NTimeSeriesLabel_10             matlab.ui.control.Label
        TS_Tab_10                       matlab.ui.control.NumericEditField
        ChannelALabel_10                matlab.ui.control.Label
        ChA_Tab_10                      matlab.ui.control.DropDown
        ExperimentIDLabel_10            matlab.ui.control.Label
        experimentID_Tab_10             matlab.ui.control.EditField
        activateTab_10                  matlab.ui.control.CheckBox
        xDimEditFieldLabel_10           matlab.ui.control.Label
        xDim_Tab_10                     matlab.ui.control.NumericEditField
        ImagepropertiesLabel_10         matlab.ui.control.Label
        yDimEditFieldLabel_10           matlab.ui.control.Label
        yDim_Tab_10                     matlab.ui.control.NumericEditField
        zDimEditFieldLabel_10           matlab.ui.control.Label
        zDim_Tab_10                     matlab.ui.control.NumericEditField
        includeTS_group_Tab_10          matlab.ui.container.ButtonGroup
        includeTS_all_Tab_10            matlab.ui.control.RadioButton
        includeTS_selected_Tab_10       matlab.ui.control.RadioButton
        ChannelBLabel_10                matlab.ui.control.Label
        ChB_Tab_10                      matlab.ui.control.DropDown
        ChannelCLabel_10                matlab.ui.control.Label
        ChC_Tab_10                      matlab.ui.control.DropDown
        ControlpositionsPanel_10        matlab.ui.container.Panel
        exp10_pos1                      matlab.ui.control.CheckBox
        exp10_pos2                      matlab.ui.control.CheckBox
        exp10_pos3                      matlab.ui.control.CheckBox
        exp10_pos4                      matlab.ui.control.CheckBox
        exp10_pos5                      matlab.ui.control.CheckBox
        exp10_pos6                      matlab.ui.control.CheckBox
        exp10_pos7                      matlab.ui.control.CheckBox
        exp10_pos8                      matlab.ui.control.CheckBox
        exp10_pos9                      matlab.ui.control.CheckBox
        exp10_pos10                     matlab.ui.control.CheckBox
        TabGroup4                       matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        ImageProcessingPanel            matlab.ui.container.Panel
        PrimaryprocessingButton         matlab.ui.control.Button
        CreatefoldersButton             matlab.ui.control.Button
        SavecontrolpositionsButton      matlab.ui.control.Button
        secondaryProcessing             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EventROIsTab                    matlab.ui.container.Tab
        CreateEventROIsPanel            matlab.ui.container.Panel
        ExtendROIEditFieldLabel         matlab.ui.control.Label
        ExtendROI                       matlab.ui.control.NumericEditField
        TimesPreEditFieldLabel          matlab.ui.control.Label
        TimesPre                        matlab.ui.control.NumericEditField
        TimesPostEditFieldLabel         matlab.ui.control.Label
        TimesPost                       matlab.ui.control.NumericEditField
        IncludeTimeSeriesButtonGroup    matlab.ui.container.ButtonGroup
        roiTS_all                       matlab.ui.control.RadioButton
        roiTS_selected                  matlab.ui.control.RadioButton
        IncludeEventsButtonGroup        matlab.ui.container.ButtonGroup
        roiEvents_all                   matlab.ui.control.RadioButton
        roiEvents_selected              matlab.ui.control.RadioButton
        RunCreateROIs                   matlab.ui.control.Button
        EventPanelsTab                  matlab.ui.container.Tab
        CreateEventROIPanel             matlab.ui.container.Panel
        ExtendPlanesEditFieldLabel      matlab.ui.control.Label
        ExtendPlanes                    matlab.ui.control.NumericEditField
        xSpacersEditFieldLabel          matlab.ui.control.Label
        xSpacers                        matlab.ui.control.NumericEditField
        CreateROIPanelButton            matlab.ui.control.Button
        IncludeTimeSeriesButtonGroup_3  matlab.ui.container.ButtonGroup
        eventPanelTS_all                matlab.ui.control.RadioButton
        eventPanelTS_selected           matlab.ui.control.RadioButton
        IncludeEventsButtonGroup_2      matlab.ui.container.ButtonGroup
        eventPanelEvents_all            matlab.ui.control.RadioButton
        eventPanelEvents_selected       matlab.ui.control.RadioButton
        IncludeCellsButtonGroup         matlab.ui.container.ButtonGroup
        eventPanelCells_all             matlab.ui.control.RadioButton
        eventPanelCells_selected        matlab.ui.control.RadioButton
        ySpacersEditFieldLabel          matlab.ui.control.Label
        ySpacers                        matlab.ui.control.NumericEditField
        xPanelDimEditFieldLabel         matlab.ui.control.Label
        xPanelDim                       matlab.ui.control.NumericEditField
        yPanelDimEditFieldLabel         matlab.ui.control.Label
        yPanelDim                       matlab.ui.control.NumericEditField
        ExportFolderTimeSeriesFolderEditFieldLabel  matlab.ui.control.Label
        exportFolder                    matlab.ui.control.EditField
        PanelNameEditFieldLabel         matlab.ui.control.Label
        panelName                       matlab.ui.control.EditField
        IncludeeventswithvalidationstatusButtonGroup  matlab.ui.container.ButtonGroup
        notValidatedEvents              matlab.ui.control.RadioButton
        validatedEvents                 matlab.ui.control.RadioButton
        falseEvents                     matlab.ui.control.RadioButton
        allEvents                       matlab.ui.control.RadioButton
        inthcellLabel                   matlab.ui.control.Label
        IncludeeventsappearingDropDownLabel  matlab.ui.control.Label
        includeEventOrder               matlab.ui.control.DropDown
        EventvalidationTab              matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        PanelfolderpathEditFieldLabel   matlab.ui.control.Label
        panelFolderPath                 matlab.ui.control.EditField
        noFalseEvents                   matlab.ui.control.CheckBox
        addValidationData               matlab.ui.control.Button
        CollectTab                      matlab.ui.container.Tab
        CollectTrackingDataPanel        matlab.ui.container.Panel
        IncludeTimeSeriesButtonGroup_4  matlab.ui.container.ButtonGroup
        collectTS_all                   matlab.ui.control.RadioButton
        collectTS_selected              matlab.ui.control.RadioButton
        IncludeEventsButtonGroup_3      matlab.ui.container.ButtonGroup
        collectEvents_all               matlab.ui.control.RadioButton
        collectEvents_selected          matlab.ui.control.RadioButton
        CollectDataButton               matlab.ui.control.Button
        collect_eventCells_timeShifted_primary  matlab.ui.control.CheckBox
        collect_eventCells_timeShifted_secondary  matlab.ui.control.CheckBox
        collect_eventCells_timeShifted_tertiary  matlab.ui.control.CheckBox
        collect_eventCells_timeShifted_quatery  matlab.ui.control.CheckBox
        collect_nonEvenCells            matlab.ui.control.CheckBox
        collect_mitosisCells_nonEvenCells_timeShifted  matlab.ui.control.CheckBox
        collect_mitosisCells_evenCells_timeShifted  matlab.ui.control.CheckBox
        collect_apoptoticEventCells     matlab.ui.control.CheckBox
        collect_apoptoticNonEventCells  matlab.ui.control.CheckBox
        collect_timediff_event_mitosis  matlab.ui.control.CheckBox
        collect_nCellsWithEvents        matlab.ui.control.CheckBox
        collect_controlCells            matlab.ui.control.CheckBox
        SelectdatatocollectLabel        matlab.ui.control.Label
        collect_normGFP                 matlab.ui.control.CheckBox
        SettingsTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        HomeFolderTab                   matlab.ui.container.Tab
        DirectoryPathLabel              matlab.ui.control.Label
        Home                            matlab.ui.control.EditField
        ExperimentsLabel                matlab.ui.control.Label
        ProcessingLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BleachCalcButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Calculate bleach correction');
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Collect tracking data')
        end

        % Button pushed function: CreateROIPanelButton
        function CreateROIPanelButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Create event panel');
        end

        % Button pushed function: CreatefoldersButton
        function CreatefoldersButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Create folders');
        end

        % Callback function
        function DetectEventsButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Detect release events');
        end

        % Callback function
        function NormalizeDataButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Normalize tracking data')
        end

        % Callback function
        function PartitionTracksButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Partition manTracks');
        end

        % Button pushed function: PrimaryprocessingButton
        function PrimaryprocessingButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Primary processing');
        end

        % Callback function
        function RunButton_12Pushed(app, event)
         cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'OfflineStorageOps');   
        end

        % Button pushed function: RunCreateROIs
        function RunCreateROIsPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Create ROIs')
        end

        % Button pushed function: SavecontrolpositionsButton
        function SavecontrolpositionsButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Define controls');
        end

        % Callback function
        function SavemetadataButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Modify tracking parameters');
        end

        % Callback function
        function TrackCompartmentsButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Track events')
        end

        % Button pushed function: addValidationData
        function addValidationDataButtonPushed(app, event)
           cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Add validation data'); 
        end

        % Callback function
        function exportPanel(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Export panel');
        end

        % Callback function
        function runCargoTrackingButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Cargo Tracking');
        end

        % Button pushed function: secondaryProcessing
        function secondaryProcessingButtonPushed(app, event)
            cd ([app.Home.Value filesep 'Processing tools' filesep 'Matlab'])
            runApp(app, 'Secondary processing');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cytosolQuant2022UIFigure = uifigure('Visible', 'off');
            app.cytosolQuant2022UIFigure.Name = 'cytosolQuant 2022';
            app.TabGroup = uitabgroup(app.cytosolQuant2022UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1';
            app.NTimeSeriesLabel = uilabel(app.Tab);
            app.NTimeSeriesLabel.Text = 'N Time Series';
            app.TS_Tab = uieditfield(app.Tab, 'numeric');
            app.TS_Tab.Value = 1;
            app.ChannelALabel = uilabel(app.Tab);
            app.ChannelALabel.Text = 'Channel A';
            app.ChA_Tab = uidropdown(app.Tab);
            app.ChA_Tab.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab.Editable = 'on';
            app.ChA_Tab.Value = 'd1-eGFP';
            app.ExperimentIDLabel = uilabel(app.Tab);
            app.ExperimentIDLabel.Text = 'Experiment ID';
            app.experimentID_Tab = uieditfield(app.Tab, 'text');
            app.activateTab = uicheckbox(app.Tab);
            app.activateTab.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel = uilabel(app.Tab);
            app.xDimEditFieldLabel.Text = 'x Dim';
            app.xDim_Tab = uieditfield(app.Tab, 'numeric');
            app.xDim_Tab.Value = 1024;
            app.ImagepropertiesLabel = uilabel(app.Tab);
            app.ImagepropertiesLabel.Text = 'Image properties';
            app.yDimEditFieldLabel = uilabel(app.Tab);
            app.yDimEditFieldLabel.Text = 'y Dim';
            app.yDim_Tab = uieditfield(app.Tab, 'numeric');
            app.yDim_Tab.Value = 1024;
            app.zDimEditFieldLabel = uilabel(app.Tab);
            app.zDimEditFieldLabel.Text = 'z Dim';
            app.zDim_Tab = uieditfield(app.Tab, 'numeric');
            app.zDim_Tab.Value = 2;
            app.includeTS_group_Tab = uibuttongroup(app.Tab);
            app.includeTS_group_Tab.Title = 'Include Time Series';
            app.includeTS_all_Tab = uiradiobutton(app.includeTS_group_Tab);
            app.includeTS_all_Tab.Text = 'All';
            app.includeTS_all_Tab.Value = true;
            app.includeTS_selected_Tab = uiradiobutton(app.includeTS_group_Tab);
            app.includeTS_selected_Tab.Text = 'Selected';
            app.ChannelBLabel = uilabel(app.Tab);
            app.ChannelBLabel.Text = 'Channel B';
            app.ChB_Tab = uidropdown(app.Tab);
            app.ChB_Tab.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab.Editable = 'on';
            app.ChB_Tab.Value = 'H33342';
            app.ChannelCLabel = uilabel(app.Tab);
            app.ChannelCLabel.Text = 'Channel C';
            app.ChC_Tab = uidropdown(app.Tab);
            app.ChC_Tab.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab.Editable = 'on';
            app.ChC_Tab.Value = 'AF647-siRNA';
            app.ControlpositionsPanel = uipanel(app.Tab);
            app.ControlpositionsPanel.Title = 'Control positions';
            app.exp1_pos1 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos1.Text = '1';
            app.exp1_pos2 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos2.Text = '2';
            app.exp1_pos3 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos3.Text = '3';
            app.exp1_pos4 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos4.Text = '4';
            app.exp1_pos5 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos5.Text = '5';
            app.exp1_pos6 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos6.Text = '6';
            app.exp1_pos7 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos7.Text = '7';
            app.exp1_pos8 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos8.Text = '8';
            app.exp1_pos9 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos9.Text = '9';
            app.exp1_pos10 = uicheckbox(app.ControlpositionsPanel);
            app.exp1_pos10.Text = '10';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2';
            app.NTimeSeriesLabel_2 = uilabel(app.Tab_2);
            app.NTimeSeriesLabel_2.Text = 'N Time Series';
            app.TS_Tab_2 = uieditfield(app.Tab_2, 'numeric');
            app.TS_Tab_2.Value = 1;
            app.ChannelALabel_2 = uilabel(app.Tab_2);
            app.ChannelALabel_2.Text = 'Channel A';
            app.ChA_Tab_2 = uidropdown(app.Tab_2);
            app.ChA_Tab_2.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_2.Editable = 'on';
            app.ChA_Tab_2.Value = 'd1-eGFP';
            app.ExperimentIDLabel_2 = uilabel(app.Tab_2);
            app.ExperimentIDLabel_2.Text = 'Experiment ID';
            app.experimentID_Tab_2 = uieditfield(app.Tab_2, 'text');
            app.activateTab_2 = uicheckbox(app.Tab_2);
            app.activateTab_2.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_2 = uilabel(app.Tab_2);
            app.xDimEditFieldLabel_2.Text = 'x Dim';
            app.xDim_Tab_2 = uieditfield(app.Tab_2, 'numeric');
            app.xDim_Tab_2.Value = 1024;
            app.ImagepropertiesLabel_2 = uilabel(app.Tab_2);
            app.ImagepropertiesLabel_2.Text = 'Image properties';
            app.yDimEditFieldLabel_2 = uilabel(app.Tab_2);
            app.yDimEditFieldLabel_2.Text = 'y Dim';
            app.yDim_Tab_2 = uieditfield(app.Tab_2, 'numeric');
            app.yDim_Tab_2.Value = 1024;
            app.zDimEditFieldLabel_2 = uilabel(app.Tab_2);
            app.zDimEditFieldLabel_2.Text = 'z Dim';
            app.zDim_Tab_2 = uieditfield(app.Tab_2, 'numeric');
            app.zDim_Tab_2.Value = 2;
            app.includeTS_group_Tab_2 = uibuttongroup(app.Tab_2);
            app.includeTS_group_Tab_2.Title = 'Include Time Series';
            app.includeTS_all_Tab_2 = uiradiobutton(app.includeTS_group_Tab_2);
            app.includeTS_all_Tab_2.Text = 'All';
            app.includeTS_all_Tab_2.Value = true;
            app.includeTS_selected_Tab_2 = uiradiobutton(app.includeTS_group_Tab_2);
            app.includeTS_selected_Tab_2.Text = 'Selected';
            app.ChannelBLabel_2 = uilabel(app.Tab_2);
            app.ChannelBLabel_2.Text = 'Channel B';
            app.ChB_Tab_2 = uidropdown(app.Tab_2);
            app.ChB_Tab_2.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_2.Editable = 'on';
            app.ChB_Tab_2.Value = 'H33342';
            app.ChannelCLabel_2 = uilabel(app.Tab_2);
            app.ChannelCLabel_2.Text = 'Channel C';
            app.ChC_Tab_2 = uidropdown(app.Tab_2);
            app.ChC_Tab_2.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_2.Editable = 'on';
            app.ChC_Tab_2.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_2 = uipanel(app.Tab_2);
            app.ControlpositionsPanel_2.Title = 'Control positions';
            app.exp2_pos1 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos1.Text = '1';
            app.exp2_pos2 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos2.Text = '2';
            app.exp2_pos3 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos3.Text = '3';
            app.exp2_pos4 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos4.Text = '4';
            app.exp2_pos5 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos5.Text = '5';
            app.exp2_pos6 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos6.Text = '6';
            app.exp2_pos7 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos7.Text = '7';
            app.exp2_pos8 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos8.Text = '8';
            app.exp2_pos9 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos9.Text = '9';
            app.exp2_pos10 = uicheckbox(app.ControlpositionsPanel_2);
            app.exp2_pos10.Text = '10';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3';
            app.NTimeSeriesLabel_3 = uilabel(app.Tab_3);
            app.NTimeSeriesLabel_3.Text = 'N Time Series';
            app.TS_Tab_3 = uieditfield(app.Tab_3, 'numeric');
            app.TS_Tab_3.Value = 1;
            app.ChannelALabel_3 = uilabel(app.Tab_3);
            app.ChannelALabel_3.Text = 'Channel A';
            app.ChA_Tab_3 = uidropdown(app.Tab_3);
            app.ChA_Tab_3.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_3.Editable = 'on';
            app.ChA_Tab_3.Value = 'd1-eGFP';
            app.ExperimentIDLabel_3 = uilabel(app.Tab_3);
            app.ExperimentIDLabel_3.Text = 'Experiment ID';
            app.experimentID_Tab_3 = uieditfield(app.Tab_3, 'text');
            app.activateTab_3 = uicheckbox(app.Tab_3);
            app.activateTab_3.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_3 = uilabel(app.Tab_3);
            app.xDimEditFieldLabel_3.Text = 'x Dim';
            app.xDim_Tab_3 = uieditfield(app.Tab_3, 'numeric');
            app.xDim_Tab_3.Value = 1024;
            app.ImagepropertiesLabel_3 = uilabel(app.Tab_3);
            app.ImagepropertiesLabel_3.Text = 'Image properties';
            app.yDimEditFieldLabel_3 = uilabel(app.Tab_3);
            app.yDimEditFieldLabel_3.Text = 'y Dim';
            app.yDim_Tab_3 = uieditfield(app.Tab_3, 'numeric');
            app.yDim_Tab_3.Value = 1024;
            app.zDimEditFieldLabel_3 = uilabel(app.Tab_3);
            app.zDimEditFieldLabel_3.Text = 'z Dim';
            app.zDim_Tab_3 = uieditfield(app.Tab_3, 'numeric');
            app.zDim_Tab_3.Value = 2;
            app.includeTS_group_Tab_3 = uibuttongroup(app.Tab_3);
            app.includeTS_group_Tab_3.Title = 'Include Time Series';
            app.includeTS_all_Tab_3 = uiradiobutton(app.includeTS_group_Tab_3);
            app.includeTS_all_Tab_3.Text = 'All';
            app.includeTS_all_Tab_3.Value = true;
            app.includeTS_selected_Tab_3 = uiradiobutton(app.includeTS_group_Tab_3);
            app.includeTS_selected_Tab_3.Text = 'Selected';
            app.ChannelBLabel_3 = uilabel(app.Tab_3);
            app.ChannelBLabel_3.Text = 'Channel B';
            app.ChB_Tab_3 = uidropdown(app.Tab_3);
            app.ChB_Tab_3.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_3.Editable = 'on';
            app.ChB_Tab_3.Value = 'H33342';
            app.ChannelCLabel_3 = uilabel(app.Tab_3);
            app.ChannelCLabel_3.Text = 'Channel C';
            app.ChC_Tab_3 = uidropdown(app.Tab_3);
            app.ChC_Tab_3.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_3.Editable = 'on';
            app.ChC_Tab_3.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_3 = uipanel(app.Tab_3);
            app.ControlpositionsPanel_3.Title = 'Control positions';
            app.exp3_pos1 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos1.Text = '1';
            app.exp3_pos2 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos2.Text = '2';
            app.exp3_pos3 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos3.Text = '3';
            app.exp3_pos4 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos4.Text = '4';
            app.exp3_pos5 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos5.Text = '5';
            app.exp3_pos6 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos6.Text = '6';
            app.exp3_pos7 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos7.Text = '7';
            app.exp3_pos8 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos8.Text = '8';
            app.exp3_pos9 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos9.Text = '9';
            app.exp3_pos10 = uicheckbox(app.ControlpositionsPanel_3);
            app.exp3_pos10.Text = '10';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4';
            app.NTimeSeriesLabel_4 = uilabel(app.Tab_4);
            app.NTimeSeriesLabel_4.Text = 'N Time Series';
            app.TS_Tab_4 = uieditfield(app.Tab_4, 'numeric');
            app.TS_Tab_4.Value = 1;
            app.ChannelALabel_4 = uilabel(app.Tab_4);
            app.ChannelALabel_4.Text = 'Channel A';
            app.ChA_Tab_4 = uidropdown(app.Tab_4);
            app.ChA_Tab_4.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_4.Editable = 'on';
            app.ChA_Tab_4.Value = 'd1-eGFP';
            app.ExperimentIDLabel_4 = uilabel(app.Tab_4);
            app.ExperimentIDLabel_4.Text = 'Experiment ID';
            app.experimentID_Tab_4 = uieditfield(app.Tab_4, 'text');
            app.activateTab_4 = uicheckbox(app.Tab_4);
            app.activateTab_4.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_4 = uilabel(app.Tab_4);
            app.xDimEditFieldLabel_4.Text = 'x Dim';
            app.xDim_Tab_4 = uieditfield(app.Tab_4, 'numeric');
            app.xDim_Tab_4.Value = 1024;
            app.ImagepropertiesLabel_4 = uilabel(app.Tab_4);
            app.ImagepropertiesLabel_4.Text = 'Image properties';
            app.yDimEditFieldLabel_4 = uilabel(app.Tab_4);
            app.yDimEditFieldLabel_4.Text = 'y Dim';
            app.yDim_Tab_4 = uieditfield(app.Tab_4, 'numeric');
            app.yDim_Tab_4.Value = 1024;
            app.zDimEditFieldLabel_4 = uilabel(app.Tab_4);
            app.zDimEditFieldLabel_4.Text = 'z Dim';
            app.zDim_Tab_4 = uieditfield(app.Tab_4, 'numeric');
            app.zDim_Tab_4.Value = 2;
            app.includeTS_group_Tab_4 = uibuttongroup(app.Tab_4);
            app.includeTS_group_Tab_4.Title = 'Include Time Series';
            app.includeTS_all_Tab_4 = uiradiobutton(app.includeTS_group_Tab_4);
            app.includeTS_all_Tab_4.Text = 'All';
            app.includeTS_all_Tab_4.Value = true;
            app.includeTS_selected_Tab_4 = uiradiobutton(app.includeTS_group_Tab_4);
            app.includeTS_selected_Tab_4.Text = 'Selected';
            app.ChannelBLabel_4 = uilabel(app.Tab_4);
            app.ChannelBLabel_4.Text = 'Channel B';
            app.ChB_Tab_4 = uidropdown(app.Tab_4);
            app.ChB_Tab_4.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_4.Editable = 'on';
            app.ChB_Tab_4.Value = 'H33342';
            app.ChannelCLabel_4 = uilabel(app.Tab_4);
            app.ChannelCLabel_4.Text = 'Channel C';
            app.ChC_Tab_4 = uidropdown(app.Tab_4);
            app.ChC_Tab_4.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_4.Editable = 'on';
            app.ChC_Tab_4.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_4 = uipanel(app.Tab_4);
            app.ControlpositionsPanel_4.Title = 'Control positions';
            app.exp4_pos1 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos1.Text = '1';
            app.exp4_pos2 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos2.Text = '2';
            app.exp4_pos3 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos3.Text = '3';
            app.exp4_pos4 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos4.Text = '4';
            app.exp4_pos5 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos5.Text = '5';
            app.exp4_pos6 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos6.Text = '6';
            app.exp4_pos7 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos7.Text = '7';
            app.exp4_pos8 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos8.Text = '8';
            app.exp4_pos9 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos9.Text = '9';
            app.exp4_pos10 = uicheckbox(app.ControlpositionsPanel_4);
            app.exp4_pos10.Text = '10';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '5';
            app.NTimeSeriesLabel_5 = uilabel(app.Tab_5);
            app.NTimeSeriesLabel_5.Text = 'N Time Series';
            app.TS_Tab_5 = uieditfield(app.Tab_5, 'numeric');
            app.TS_Tab_5.Value = 1;
            app.ChannelALabel_5 = uilabel(app.Tab_5);
            app.ChannelALabel_5.Text = 'Channel A';
            app.ChA_Tab_5 = uidropdown(app.Tab_5);
            app.ChA_Tab_5.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_5.Editable = 'on';
            app.ChA_Tab_5.Value = 'd1-eGFP';
            app.ExperimentIDLabel_5 = uilabel(app.Tab_5);
            app.ExperimentIDLabel_5.Text = 'Experiment ID';
            app.experimentID_Tab_5 = uieditfield(app.Tab_5, 'text');
            app.activateTab_5 = uicheckbox(app.Tab_5);
            app.activateTab_5.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_5 = uilabel(app.Tab_5);
            app.xDimEditFieldLabel_5.Text = 'x Dim';
            app.xDim_Tab_5 = uieditfield(app.Tab_5, 'numeric');
            app.xDim_Tab_5.Value = 1024;
            app.ImagepropertiesLabel_5 = uilabel(app.Tab_5);
            app.ImagepropertiesLabel_5.Text = 'Image properties';
            app.yDimEditFieldLabel_5 = uilabel(app.Tab_5);
            app.yDimEditFieldLabel_5.Text = 'y Dim';
            app.yDim_Tab_5 = uieditfield(app.Tab_5, 'numeric');
            app.yDim_Tab_5.Value = 1024;
            app.zDimEditFieldLabel_5 = uilabel(app.Tab_5);
            app.zDimEditFieldLabel_5.Text = 'z Dim';
            app.zDim_Tab_5 = uieditfield(app.Tab_5, 'numeric');
            app.zDim_Tab_5.Value = 2;
            app.includeTS_group_Tab_5 = uibuttongroup(app.Tab_5);
            app.includeTS_group_Tab_5.Title = 'Include Time Series';
            app.includeTS_all_Tab_5 = uiradiobutton(app.includeTS_group_Tab_5);
            app.includeTS_all_Tab_5.Text = 'All';
            app.includeTS_all_Tab_5.Value = true;
            app.includeTS_selected_Tab_5 = uiradiobutton(app.includeTS_group_Tab_5);
            app.includeTS_selected_Tab_5.Text = 'Selected';
            app.ChannelBLabel_5 = uilabel(app.Tab_5);
            app.ChannelBLabel_5.Text = 'Channel B';
            app.ChB_Tab_5 = uidropdown(app.Tab_5);
            app.ChB_Tab_5.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_5.Editable = 'on';
            app.ChB_Tab_5.Value = 'H33342';
            app.ChannelCLabel_5 = uilabel(app.Tab_5);
            app.ChannelCLabel_5.Text = 'Channel C';
            app.ChC_Tab_5 = uidropdown(app.Tab_5);
            app.ChC_Tab_5.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_5.Editable = 'on';
            app.ChC_Tab_5.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_5 = uipanel(app.Tab_5);
            app.ControlpositionsPanel_5.Title = 'Control positions';
            app.exp5_pos1 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos1.Text = '1';
            app.exp5_pos2 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos2.Text = '2';
            app.exp5_pos3 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos3.Text = '3';
            app.exp5_pos4 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos4.Text = '4';
            app.exp5_pos5 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos5.Text = '5';
            app.exp5_pos6 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos6.Text = '6';
            app.exp5_pos7 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos7.Text = '7';
            app.exp5_pos8 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos8.Text = '8';
            app.exp5_pos9 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos9.Text = '9';
            app.exp5_pos10 = uicheckbox(app.ControlpositionsPanel_5);
            app.exp5_pos10.Text = '10';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '6';
            app.NTimeSeriesLabel_6 = uilabel(app.Tab_6);
            app.NTimeSeriesLabel_6.Text = 'N Time Series';
            app.TS_Tab_6 = uieditfield(app.Tab_6, 'numeric');
            app.TS_Tab_6.Value = 1;
            app.ChannelALabel_6 = uilabel(app.Tab_6);
            app.ChannelALabel_6.Text = 'Channel A';
            app.ChA_Tab_6 = uidropdown(app.Tab_6);
            app.ChA_Tab_6.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_6.Editable = 'on';
            app.ChA_Tab_6.Value = 'd1-eGFP';
            app.ExperimentIDLabel_6 = uilabel(app.Tab_6);
            app.ExperimentIDLabel_6.Text = 'Experiment ID';
            app.experimentID_Tab_6 = uieditfield(app.Tab_6, 'text');
            app.activateTab_6 = uicheckbox(app.Tab_6);
            app.activateTab_6.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_6 = uilabel(app.Tab_6);
            app.xDimEditFieldLabel_6.Text = 'x Dim';
            app.xDim_Tab_6 = uieditfield(app.Tab_6, 'numeric');
            app.xDim_Tab_6.Value = 1024;
            app.ImagepropertiesLabel_6 = uilabel(app.Tab_6);
            app.ImagepropertiesLabel_6.Text = 'Image properties';
            app.yDimEditFieldLabel_6 = uilabel(app.Tab_6);
            app.yDimEditFieldLabel_6.Text = 'y Dim';
            app.yDim_Tab_6 = uieditfield(app.Tab_6, 'numeric');
            app.yDim_Tab_6.Value = 1024;
            app.zDimEditFieldLabel_6 = uilabel(app.Tab_6);
            app.zDimEditFieldLabel_6.Text = 'z Dim';
            app.zDim_Tab_6 = uieditfield(app.Tab_6, 'numeric');
            app.zDim_Tab_6.Value = 2;
            app.includeTS_group_Tab_6 = uibuttongroup(app.Tab_6);
            app.includeTS_group_Tab_6.Title = 'Include Time Series';
            app.includeTS_all_Tab_6 = uiradiobutton(app.includeTS_group_Tab_6);
            app.includeTS_all_Tab_6.Text = 'All';
            app.includeTS_all_Tab_6.Value = true;
            app.includeTS_selected_Tab_6 = uiradiobutton(app.includeTS_group_Tab_6);
            app.includeTS_selected_Tab_6.Text = 'Selected';
            app.ChannelBLabel_6 = uilabel(app.Tab_6);
            app.ChannelBLabel_6.Text = 'Channel B';
            app.ChB_Tab_6 = uidropdown(app.Tab_6);
            app.ChB_Tab_6.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_6.Editable = 'on';
            app.ChB_Tab_6.Value = 'H33342';
            app.ChannelCLabel_6 = uilabel(app.Tab_6);
            app.ChannelCLabel_6.Text = 'Channel C';
            app.ChC_Tab_6 = uidropdown(app.Tab_6);
            app.ChC_Tab_6.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_6.Editable = 'on';
            app.ChC_Tab_6.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_6 = uipanel(app.Tab_6);
            app.ControlpositionsPanel_6.Title = 'Control positions';
            app.exp6_pos1 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos1.Text = '1';
            app.exp6_pos2 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos2.Text = '2';
            app.exp6_pos3 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos3.Text = '3';
            app.exp6_pos4 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos4.Text = '4';
            app.exp6_pos5 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos5.Text = '5';
            app.exp6_pos6 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos6.Text = '6';
            app.exp6_pos7 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos7.Text = '7';
            app.exp6_pos8 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos8.Text = '8';
            app.exp6_pos9 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos9.Text = '9';
            app.exp6_pos10 = uicheckbox(app.ControlpositionsPanel_6);
            app.exp6_pos10.Text = '10';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '7';
            app.NTimeSeriesLabel_7 = uilabel(app.Tab_7);
            app.NTimeSeriesLabel_7.Text = 'N Time Series';
            app.TS_Tab_7 = uieditfield(app.Tab_7, 'numeric');
            app.TS_Tab_7.Value = 1;
            app.ChannelALabel_7 = uilabel(app.Tab_7);
            app.ChannelALabel_7.Text = 'Channel A';
            app.ChA_Tab_7 = uidropdown(app.Tab_7);
            app.ChA_Tab_7.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_7.Editable = 'on';
            app.ChA_Tab_7.Value = 'd1-eGFP';
            app.ExperimentIDLabel_7 = uilabel(app.Tab_7);
            app.ExperimentIDLabel_7.Text = 'Experiment ID';
            app.experimentID_Tab_7 = uieditfield(app.Tab_7, 'text');
            app.activateTab_7 = uicheckbox(app.Tab_7);
            app.activateTab_7.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_7 = uilabel(app.Tab_7);
            app.xDimEditFieldLabel_7.Text = 'x Dim';
            app.xDim_Tab_7 = uieditfield(app.Tab_7, 'numeric');
            app.xDim_Tab_7.Value = 1024;
            app.ImagepropertiesLabel_7 = uilabel(app.Tab_7);
            app.ImagepropertiesLabel_7.Text = 'Image properties';
            app.yDimEditFieldLabel_7 = uilabel(app.Tab_7);
            app.yDimEditFieldLabel_7.Text = 'y Dim';
            app.yDim_Tab_7 = uieditfield(app.Tab_7, 'numeric');
            app.yDim_Tab_7.Value = 1024;
            app.zDimEditFieldLabel_7 = uilabel(app.Tab_7);
            app.zDimEditFieldLabel_7.Text = 'z Dim';
            app.zDim_Tab_7 = uieditfield(app.Tab_7, 'numeric');
            app.zDim_Tab_7.Value = 2;
            app.includeTS_group_Tab_7 = uibuttongroup(app.Tab_7);
            app.includeTS_group_Tab_7.Title = 'Include Time Series';
            app.includeTS_all_Tab_7 = uiradiobutton(app.includeTS_group_Tab_7);
            app.includeTS_all_Tab_7.Text = 'All';
            app.includeTS_all_Tab_7.Value = true;
            app.includeTS_selected_Tab_7 = uiradiobutton(app.includeTS_group_Tab_7);
            app.includeTS_selected_Tab_7.Text = 'Selected';
            app.ChannelBLabel_7 = uilabel(app.Tab_7);
            app.ChannelBLabel_7.Text = 'Channel B';
            app.ChB_Tab_7 = uidropdown(app.Tab_7);
            app.ChB_Tab_7.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_7.Editable = 'on';
            app.ChB_Tab_7.Value = 'H33342';
            app.ChannelCLabel_7 = uilabel(app.Tab_7);
            app.ChannelCLabel_7.Text = 'Channel C';
            app.ChC_Tab_7 = uidropdown(app.Tab_7);
            app.ChC_Tab_7.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_7.Editable = 'on';
            app.ChC_Tab_7.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_7 = uipanel(app.Tab_7);
            app.ControlpositionsPanel_7.Title = 'Control positions';
            app.exp7_pos1 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos1.Text = '1';
            app.exp7_pos2 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos2.Text = '2';
            app.exp7_pos3 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos3.Text = '3';
            app.exp7_pos4 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos4.Text = '4';
            app.exp7_pos5 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos5.Text = '5';
            app.exp7_pos6 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos6.Text = '6';
            app.exp7_pos7 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos7.Text = '7';
            app.exp7_pos8 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos8.Text = '8';
            app.exp7_pos9 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos9.Text = '9';
            app.exp7_pos10 = uicheckbox(app.ControlpositionsPanel_7);
            app.exp7_pos10.Text = '10';
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '8';
            app.NTimeSeriesLabel_8 = uilabel(app.Tab_8);
            app.NTimeSeriesLabel_8.Text = 'N Time Series';
            app.TS_Tab_8 = uieditfield(app.Tab_8, 'numeric');
            app.TS_Tab_8.Value = 1;
            app.ChannelALabel_8 = uilabel(app.Tab_8);
            app.ChannelALabel_8.Text = 'Channel A';
            app.ChA_Tab_8 = uidropdown(app.Tab_8);
            app.ChA_Tab_8.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_8.Editable = 'on';
            app.ChA_Tab_8.Value = 'd1-eGFP';
            app.ExperimentIDLabel_8 = uilabel(app.Tab_8);
            app.ExperimentIDLabel_8.Text = 'Experiment ID';
            app.experimentID_Tab_8 = uieditfield(app.Tab_8, 'text');
            app.activateTab_8 = uicheckbox(app.Tab_8);
            app.activateTab_8.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_8 = uilabel(app.Tab_8);
            app.xDimEditFieldLabel_8.Text = 'x Dim';
            app.xDim_Tab_8 = uieditfield(app.Tab_8, 'numeric');
            app.xDim_Tab_8.Value = 1024;
            app.ImagepropertiesLabel_8 = uilabel(app.Tab_8);
            app.ImagepropertiesLabel_8.Text = 'Image properties';
            app.yDimEditFieldLabel_8 = uilabel(app.Tab_8);
            app.yDimEditFieldLabel_8.Text = 'y Dim';
            app.yDim_Tab_8 = uieditfield(app.Tab_8, 'numeric');
            app.yDim_Tab_8.Value = 1024;
            app.zDimEditFieldLabel_8 = uilabel(app.Tab_8);
            app.zDimEditFieldLabel_8.Text = 'z Dim';
            app.zDim_Tab_8 = uieditfield(app.Tab_8, 'numeric');
            app.zDim_Tab_8.Value = 2;
            app.includeTS_group_Tab_8 = uibuttongroup(app.Tab_8);
            app.includeTS_group_Tab_8.Title = 'Include Time Series';
            app.includeTS_all_Tab_8 = uiradiobutton(app.includeTS_group_Tab_8);
            app.includeTS_all_Tab_8.Text = 'All';
            app.includeTS_all_Tab_8.Value = true;
            app.includeTS_selected_Tab_8 = uiradiobutton(app.includeTS_group_Tab_8);
            app.includeTS_selected_Tab_8.Text = 'Selected';
            app.ChannelBLabel_8 = uilabel(app.Tab_8);
            app.ChannelBLabel_8.Text = 'Channel B';
            app.ChB_Tab_8 = uidropdown(app.Tab_8);
            app.ChB_Tab_8.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_8.Editable = 'on';
            app.ChB_Tab_8.Value = 'H33342';
            app.ChannelCLabel_8 = uilabel(app.Tab_8);
            app.ChannelCLabel_8.Text = 'Channel C';
            app.ChC_Tab_8 = uidropdown(app.Tab_8);
            app.ChC_Tab_8.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_8.Editable = 'on';
            app.ChC_Tab_8.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_8 = uipanel(app.Tab_8);
            app.ControlpositionsPanel_8.Title = 'Control positions';
            app.exp8_pos1 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos1.Text = '1';
            app.exp8_pos2 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos2.Text = '2';
            app.exp8_pos3 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos3.Text = '3';
            app.exp8_pos4 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos4.Text = '4';
            app.exp8_pos5 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos5.Text = '5';
            app.exp8_pos6 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos6.Text = '6';
            app.exp8_pos7 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos7.Text = '7';
            app.exp8_pos8 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos8.Text = '8';
            app.exp8_pos9 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos9.Text = '9';
            app.exp8_pos10 = uicheckbox(app.ControlpositionsPanel_8);
            app.exp8_pos10.Text = '10';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '9';
            app.NTimeSeriesLabel_9 = uilabel(app.Tab_9);
            app.NTimeSeriesLabel_9.Text = 'N Time Series';
            app.TS_Tab_9 = uieditfield(app.Tab_9, 'numeric');
            app.TS_Tab_9.Value = 1;
            app.ChannelALabel_9 = uilabel(app.Tab_9);
            app.ChannelALabel_9.Text = 'Channel A';
            app.ChA_Tab_9 = uidropdown(app.Tab_9);
            app.ChA_Tab_9.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_9.Editable = 'on';
            app.ChA_Tab_9.Value = 'd1-eGFP';
            app.ExperimentIDLabel_9 = uilabel(app.Tab_9);
            app.ExperimentIDLabel_9.Text = 'Experiment ID';
            app.experimentID_Tab_9 = uieditfield(app.Tab_9, 'text');
            app.activateTab_9 = uicheckbox(app.Tab_9);
            app.activateTab_9.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_9 = uilabel(app.Tab_9);
            app.xDimEditFieldLabel_9.Text = 'x Dim';
            app.xDim_Tab_9 = uieditfield(app.Tab_9, 'numeric');
            app.xDim_Tab_9.Value = 1024;
            app.ImagepropertiesLabel_9 = uilabel(app.Tab_9);
            app.ImagepropertiesLabel_9.Text = 'Image properties';
            app.yDimEditFieldLabel_9 = uilabel(app.Tab_9);
            app.yDimEditFieldLabel_9.Text = 'y Dim';
            app.yDim_Tab_9 = uieditfield(app.Tab_9, 'numeric');
            app.yDim_Tab_9.Value = 1024;
            app.zDimEditFieldLabel_9 = uilabel(app.Tab_9);
            app.zDimEditFieldLabel_9.Text = 'z Dim';
            app.zDim_Tab_9 = uieditfield(app.Tab_9, 'numeric');
            app.zDim_Tab_9.Value = 2;
            app.includeTS_group_Tab_9 = uibuttongroup(app.Tab_9);
            app.includeTS_group_Tab_9.Title = 'Include Time Series';
            app.includeTS_all_Tab_9 = uiradiobutton(app.includeTS_group_Tab_9);
            app.includeTS_all_Tab_9.Text = 'All';
            app.includeTS_all_Tab_9.Value = true;
            app.includeTS_selected_Tab_9 = uiradiobutton(app.includeTS_group_Tab_9);
            app.includeTS_selected_Tab_9.Text = 'Selected';
            app.ChannelBLabel_9 = uilabel(app.Tab_9);
            app.ChannelBLabel_9.Text = 'Channel B';
            app.ChB_Tab_9 = uidropdown(app.Tab_9);
            app.ChB_Tab_9.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_9.Editable = 'on';
            app.ChB_Tab_9.Value = 'H33342';
            app.ChannelCLabel_9 = uilabel(app.Tab_9);
            app.ChannelCLabel_9.Text = 'Channel C';
            app.ChC_Tab_9 = uidropdown(app.Tab_9);
            app.ChC_Tab_9.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_9.Editable = 'on';
            app.ChC_Tab_9.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_9 = uipanel(app.Tab_9);
            app.ControlpositionsPanel_9.Title = 'Control positions';
            app.exp9_pos1 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos1.Text = '1';
            app.exp9_pos2 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos2.Text = '2';
            app.exp9_pos3 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos3.Text = '3';
            app.exp9_pos4 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos4.Text = '4';
            app.exp9_pos5 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos5.Text = '5';
            app.exp9_pos6 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos6.Text = '6';
            app.exp9_pos7 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos7.Text = '7';
            app.exp9_pos8 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos8.Text = '8';
            app.exp9_pos9 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos9.Text = '9';
            app.exp9_pos10 = uicheckbox(app.ControlpositionsPanel_9);
            app.exp9_pos10.Text = '10';
            app.Tab_10 = uitab(app.TabGroup);
            app.Tab_10.Title = '10';
            app.NTimeSeriesLabel_10 = uilabel(app.Tab_10);
            app.NTimeSeriesLabel_10.Text = 'N Time Series';
            app.TS_Tab_10 = uieditfield(app.Tab_10, 'numeric');
            app.TS_Tab_10.Value = 1;
            app.ChannelALabel_10 = uilabel(app.Tab_10);
            app.ChannelALabel_10.Text = 'Channel A';
            app.ChA_Tab_10 = uidropdown(app.Tab_10);
            app.ChA_Tab_10.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'd1-eGFP'};
            app.ChA_Tab_10.Editable = 'on';
            app.ChA_Tab_10.Value = 'd1-eGFP';
            app.ExperimentIDLabel_10 = uilabel(app.Tab_10);
            app.ExperimentIDLabel_10.Text = 'Experiment ID';
            app.experimentID_Tab_10 = uieditfield(app.Tab_10, 'text');
            app.activateTab_10 = uicheckbox(app.Tab_10);
            app.activateTab_10.Text = 'Activate experiment tab';
            app.xDimEditFieldLabel_10 = uilabel(app.Tab_10);
            app.xDimEditFieldLabel_10.Text = 'x Dim';
            app.xDim_Tab_10 = uieditfield(app.Tab_10, 'numeric');
            app.xDim_Tab_10.Value = 1024;
            app.ImagepropertiesLabel_10 = uilabel(app.Tab_10);
            app.ImagepropertiesLabel_10.Text = 'Image properties';
            app.yDimEditFieldLabel_10 = uilabel(app.Tab_10);
            app.yDimEditFieldLabel_10.Text = 'y Dim';
            app.yDim_Tab_10 = uieditfield(app.Tab_10, 'numeric');
            app.yDim_Tab_10.Value = 1024;
            app.zDimEditFieldLabel_10 = uilabel(app.Tab_10);
            app.zDimEditFieldLabel_10.Text = 'z Dim';
            app.zDim_Tab_10 = uieditfield(app.Tab_10, 'numeric');
            app.zDim_Tab_10.Value = 2;
            app.includeTS_group_Tab_10 = uibuttongroup(app.Tab_10);
            app.includeTS_group_Tab_10.Title = 'Include Time Series';
            app.includeTS_all_Tab_10 = uiradiobutton(app.includeTS_group_Tab_10);
            app.includeTS_all_Tab_10.Text = 'All';
            app.includeTS_all_Tab_10.Value = true;
            app.includeTS_selected_Tab_10 = uiradiobutton(app.includeTS_group_Tab_10);
            app.includeTS_selected_Tab_10.Text = 'Selected';
            app.ChannelBLabel_10 = uilabel(app.Tab_10);
            app.ChannelBLabel_10.Text = 'Channel B';
            app.ChB_Tab_10 = uidropdown(app.Tab_10);
            app.ChB_Tab_10.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'H33342'};
            app.ChB_Tab_10.Editable = 'on';
            app.ChB_Tab_10.Value = 'H33342';
            app.ChannelCLabel_10 = uilabel(app.Tab_10);
            app.ChannelCLabel_10.Text = 'Channel C';
            app.ChC_Tab_10 = uidropdown(app.Tab_10);
            app.ChC_Tab_10.Items = {'Not selected', 'YFP-Galectin9', 'YFP-Galectin8', 'GFP-Galectin3', 'GFP-LAMP1', 'GFP-LC3B', 'OG488-Dextran', 'mCherry-Galectin9', 'mCherry-Galectin3', 'mCherry-LAMP1', 'mCherry-LC3B', 'mCherry-CD63', 'mCherry-Rab7', 'mCherry-Rab5', 'mCherry-Rab4a', 'mCherry-GalT', 'mCherry-Mito', 'mCherry-Peroxisomes', 'mCherry-ER', 'mCherry-TGN46', 'AF647-Dextran', 'DY546-Accell-siRNA', 'AF647-EpCAM', 'AF647-siRNA'};
            app.ChC_Tab_10.Editable = 'on';
            app.ChC_Tab_10.Value = 'AF647-siRNA';
            app.ControlpositionsPanel_10 = uipanel(app.Tab_10);
            app.ControlpositionsPanel_10.Title = 'Control positions';
            app.exp10_pos1 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos1.Text = '1';
            app.exp10_pos2 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos2.Text = '2';
            app.exp10_pos3 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos3.Text = '3';
            app.exp10_pos4 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos4.Text = '4';
            app.exp10_pos5 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos5.Text = '5';
            app.exp10_pos6 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos6.Text = '6';
            app.exp10_pos7 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos7.Text = '7';
            app.exp10_pos8 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos8.Text = '8';
            app.exp10_pos9 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos9.Text = '9';
            app.exp10_pos10 = uicheckbox(app.ControlpositionsPanel_10);
            app.exp10_pos10.Text = '10';
            app.TabGroup4 = uitabgroup(app.cytosolQuant2022UIFigure);
            app.ProcessingTab = uitab(app.TabGroup4);
            app.ProcessingTab.Title = 'Processing';
            app.ImageProcessingPanel = uipanel(app.ProcessingTab);
            app.ImageProcessingPanel.Title = 'Image Processing';
            app.PrimaryprocessingButton = uibutton(app.ImageProcessingPanel, 'push');
            app.PrimaryprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PrimaryprocessingButtonPushed, true);
            app.PrimaryprocessingButton.Text = 'Primary processing';
            app.CreatefoldersButton = uibutton(app.ImageProcessingPanel, 'push');
            app.CreatefoldersButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefoldersButtonPushed, true);
            app.CreatefoldersButton.Text = 'Create folders';
            app.SavecontrolpositionsButton = uibutton(app.ImageProcessingPanel, 'push');
            app.SavecontrolpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @SavecontrolpositionsButtonPushed, true);
            app.SavecontrolpositionsButton.Text = ' Save control positions';
            app.secondaryProcessing = uibutton(app.ImageProcessingPanel, 'push');
            app.secondaryProcessing.ButtonPushedFcn = createCallbackFcn(app, @secondaryProcessingButtonPushed, true);
            app.secondaryProcessing.Text = 'Secondary processing';
            app.Label = uilabel(app.ImageProcessingPanel);
            app.Label.Text = {'Create event ROIs and event panels'; 'Validate events, eliminate false events'};
            app.Label_2 = uilabel(app.ImageProcessingPanel);
            app.Label_2.Text = 'Collect selected datasets ';
            app.EventROIsTab = uitab(app.TabGroup4);
            app.EventROIsTab.Title = 'Event ROIs';
            app.CreateEventROIsPanel = uipanel(app.EventROIsTab);
            app.CreateEventROIsPanel.Title = 'Create Event ROIs';
            app.ExtendROIEditFieldLabel = uilabel(app.CreateEventROIsPanel);
            app.ExtendROIEditFieldLabel.Text = 'Extend ROI';
            app.ExtendROI = uieditfield(app.CreateEventROIsPanel, 'numeric');
            app.ExtendROI.Value = 125;
            app.TimesPreEditFieldLabel = uilabel(app.CreateEventROIsPanel);
            app.TimesPreEditFieldLabel.Text = 'Times Pre';
            app.TimesPre = uieditfield(app.CreateEventROIsPanel, 'numeric');
            app.TimesPre.Value = 10;
            app.TimesPostEditFieldLabel = uilabel(app.CreateEventROIsPanel);
            app.TimesPostEditFieldLabel.Text = 'Times Post';
            app.TimesPost = uieditfield(app.CreateEventROIsPanel, 'numeric');
            app.TimesPost.Value = 20;
            app.IncludeTimeSeriesButtonGroup = uibuttongroup(app.CreateEventROIsPanel);
            app.IncludeTimeSeriesButtonGroup.Title = 'Include Time Series';
            app.roiTS_all = uiradiobutton(app.IncludeTimeSeriesButtonGroup);
            app.roiTS_all.Text = 'All';
            app.roiTS_all.Value = true;
            app.roiTS_selected = uiradiobutton(app.IncludeTimeSeriesButtonGroup);
            app.roiTS_selected.Text = 'Selected';
            app.IncludeEventsButtonGroup = uibuttongroup(app.CreateEventROIsPanel);
            app.IncludeEventsButtonGroup.Title = 'Include Events';
            app.roiEvents_all = uiradiobutton(app.IncludeEventsButtonGroup);
            app.roiEvents_all.Text = 'All';
            app.roiEvents_all.Value = true;
            app.roiEvents_selected = uiradiobutton(app.IncludeEventsButtonGroup);
            app.roiEvents_selected.Text = 'Selected';
            app.RunCreateROIs = uibutton(app.CreateEventROIsPanel, 'push');
            app.RunCreateROIs.ButtonPushedFcn = createCallbackFcn(app, @RunCreateROIsPushed, true);
            app.RunCreateROIs.Text = 'Create Event ROIs';
            app.EventPanelsTab = uitab(app.TabGroup4);
            app.EventPanelsTab.Title = 'Event Panels';
            app.CreateEventROIPanel = uipanel(app.EventPanelsTab);
            app.CreateEventROIPanel.Title = 'Create Event ROI Panel';
            app.ExtendPlanesEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.ExtendPlanesEditFieldLabel.Text = 'Extend Planes';
            app.ExtendPlanes = uieditfield(app.CreateEventROIPanel, 'numeric');
            app.ExtendPlanes.Value = 2;
            app.xSpacersEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.xSpacersEditFieldLabel.Text = 'x Spacers';
            app.xSpacers = uieditfield(app.CreateEventROIPanel, 'numeric');
            app.xSpacers.Value = 75;
            app.CreateROIPanelButton = uibutton(app.CreateEventROIPanel, 'push');
            app.CreateROIPanelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateROIPanelButtonPushed, true);
            app.CreateROIPanelButton.Text = 'Create ROI Panel';
            app.IncludeTimeSeriesButtonGroup_3 = uibuttongroup(app.CreateEventROIPanel);
            app.IncludeTimeSeriesButtonGroup_3.Title = 'Include Time Series';
            app.eventPanelTS_all = uiradiobutton(app.IncludeTimeSeriesButtonGroup_3);
            app.eventPanelTS_all.Text = 'All';
            app.eventPanelTS_all.Value = true;
            app.eventPanelTS_selected = uiradiobutton(app.IncludeTimeSeriesButtonGroup_3);
            app.eventPanelTS_selected.Text = 'Selected';
            app.IncludeEventsButtonGroup_2 = uibuttongroup(app.CreateEventROIPanel);
            app.IncludeEventsButtonGroup_2.Title = 'Include Events';
            app.eventPanelEvents_all = uiradiobutton(app.IncludeEventsButtonGroup_2);
            app.eventPanelEvents_all.Text = 'All';
            app.eventPanelEvents_all.Value = true;
            app.eventPanelEvents_selected = uiradiobutton(app.IncludeEventsButtonGroup_2);
            app.eventPanelEvents_selected.Text = 'Selected';
            app.IncludeCellsButtonGroup = uibuttongroup(app.CreateEventROIPanel);
            app.IncludeCellsButtonGroup.Title = 'Include Cells';
            app.eventPanelCells_all = uiradiobutton(app.IncludeCellsButtonGroup);
            app.eventPanelCells_all.Text = 'All';
            app.eventPanelCells_all.Value = true;
            app.eventPanelCells_selected = uiradiobutton(app.IncludeCellsButtonGroup);
            app.eventPanelCells_selected.Text = 'Selected';
            app.ySpacersEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.ySpacersEditFieldLabel.Text = 'y Spacers';
            app.ySpacers = uieditfield(app.CreateEventROIPanel, 'numeric');
            app.ySpacers.Value = 125;
            app.xPanelDimEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.xPanelDimEditFieldLabel.Text = 'x Panel Dim';
            app.xPanelDim = uieditfield(app.CreateEventROIPanel, 'numeric');
            app.xPanelDim.Value = 3500;
            app.yPanelDimEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.yPanelDimEditFieldLabel.Text = 'y Panel Dim';
            app.yPanelDim = uieditfield(app.CreateEventROIPanel, 'numeric');
            app.yPanelDim.Value = 2500;
            app.ExportFolderTimeSeriesFolderEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.ExportFolderTimeSeriesFolderEditFieldLabel.Text = 'Export Folder (Time Series Folder)';
            app.exportFolder = uieditfield(app.CreateEventROIPanel, 'text');
            app.PanelNameEditFieldLabel = uilabel(app.CreateEventROIPanel);
            app.PanelNameEditFieldLabel.Text = 'Panel Name';
            app.panelName = uieditfield(app.CreateEventROIPanel, 'text');
            app.panelName.Value = 'Panel01';
            app.IncludeeventswithvalidationstatusButtonGroup = uibuttongroup(app.CreateEventROIPanel);
            app.IncludeeventswithvalidationstatusButtonGroup.Title = 'Include events with validation status';
            app.notValidatedEvents = uiradiobutton(app.IncludeeventswithvalidationstatusButtonGroup);
            app.notValidatedEvents.Text = 'Not validated';
            app.notValidatedEvents.Value = true;
            app.validatedEvents = uiradiobutton(app.IncludeeventswithvalidationstatusButtonGroup);
            app.validatedEvents.Text = 'Validated';
            app.falseEvents = uiradiobutton(app.IncludeeventswithvalidationstatusButtonGroup);
            app.falseEvents.Text = 'False';
            app.allEvents = uiradiobutton(app.IncludeeventswithvalidationstatusButtonGroup);
            app.allEvents.Text = 'All';
            app.inthcellLabel = uilabel(app.CreateEventROIPanel);
            app.inthcellLabel.Text = 'in th cell';
            app.IncludeeventsappearingDropDownLabel = uilabel(app.CreateEventROIPanel);
            app.IncludeeventsappearingDropDownLabel.Text = 'Include events appearing';
            app.includeEventOrder = uidropdown(app.CreateEventROIPanel);
            app.includeEventOrder.Items = {'1st', '2nd', '3rd', '4th', '5th', '6th', '7th', '8th', '9th', '10th'};
            app.includeEventOrder.Value = '1st';
            app.EventvalidationTab = uitab(app.TabGroup4);
            app.EventvalidationTab.Title = 'Event validation';
            app.Label_3 = uilabel(app.EventvalidationTab);
            app.Label_3.Text = 'Add event validation or disqualification to cell data structured array';
            app.PanelfolderpathEditFieldLabel = uilabel(app.EventvalidationTab);
            app.PanelfolderpathEditFieldLabel.Text = 'Panel folder path';
            app.panelFolderPath = uieditfield(app.EventvalidationTab, 'text');
            app.noFalseEvents = uicheckbox(app.EventvalidationTab);
            app.noFalseEvents.Text = 'No false events present in panel';
            app.addValidationData = uibutton(app.EventvalidationTab, 'push');
            app.addValidationData.ButtonPushedFcn = createCallbackFcn(app, @addValidationDataButtonPushed, true);
            app.addValidationData.Text = 'Add new validation data';
            app.CollectTab = uitab(app.TabGroup4);
            app.CollectTab.Title = 'Collect';
            app.CollectTrackingDataPanel = uipanel(app.CollectTab);
            app.CollectTrackingDataPanel.Title = 'Collect Tracking Data';
            app.IncludeTimeSeriesButtonGroup_4 = uibuttongroup(app.CollectTrackingDataPanel);
            app.IncludeTimeSeriesButtonGroup_4.Title = 'Include Time Series';
            app.collectTS_all = uiradiobutton(app.IncludeTimeSeriesButtonGroup_4);
            app.collectTS_all.Text = 'All';
            app.collectTS_all.Value = true;
            app.collectTS_selected = uiradiobutton(app.IncludeTimeSeriesButtonGroup_4);
            app.collectTS_selected.Text = 'Selected';
            app.IncludeEventsButtonGroup_3 = uibuttongroup(app.CollectTrackingDataPanel);
            app.IncludeEventsButtonGroup_3.Title = 'Include Events';
            app.collectEvents_all = uiradiobutton(app.IncludeEventsButtonGroup_3);
            app.collectEvents_all.Text = 'All';
            app.collectEvents_all.Value = true;
            app.collectEvents_selected = uiradiobutton(app.IncludeEventsButtonGroup_3);
            app.collectEvents_selected.Text = 'Selected';
            app.CollectDataButton = uibutton(app.CollectTrackingDataPanel, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.collect_eventCells_timeShifted_primary = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_eventCells_timeShifted_primary.Text = 'Release events, primary';
            app.collect_eventCells_timeShifted_primary.Value = true;
            app.collect_eventCells_timeShifted_secondary = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_eventCells_timeShifted_secondary.Text = 'Release events, secondary';
            app.collect_eventCells_timeShifted_tertiary = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_eventCells_timeShifted_tertiary.Text = 'Release events, tertiary';
            app.collect_eventCells_timeShifted_quatery = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_eventCells_timeShifted_quatery.Text = 'Release events, quatery';
            app.collect_nonEvenCells = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_nonEvenCells.Text = 'Non-event cells';
            app.collect_mitosisCells_nonEvenCells_timeShifted = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_mitosisCells_nonEvenCells_timeShifted.Text = 'Non-event cells with mitosis';
            app.collect_mitosisCells_evenCells_timeShifted = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_mitosisCells_evenCells_timeShifted.Text = 'Event cells with mitosis';
            app.collect_apoptoticEventCells = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_apoptoticEventCells.Text = 'Apoptotic event cells';
            app.collect_apoptoticNonEventCells = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_apoptoticNonEventCells.Text = 'Apoptotic non-event cells';
            app.collect_timediff_event_mitosis = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_timediff_event_mitosis.Text = 'Time difference event to mitosis';
            app.collect_nCellsWithEvents = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_nCellsWithEvents.Text = 'Number of cells with events';
            app.collect_controlCells = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_controlCells.Text = 'Control cells';
            app.SelectdatatocollectLabel = uilabel(app.CollectTrackingDataPanel);
            app.SelectdatatocollectLabel.Text = 'Select data to collect';
            app.collect_normGFP = uicheckbox(app.CollectTrackingDataPanel);
            app.collect_normGFP.Text = 'Normalize d1GFP traces';
            app.collect_normGFP.Value = true;
            app.SettingsTab = uitab(app.TabGroup4);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup3 = uitabgroup(app.SettingsTab);
            app.HomeFolderTab = uitab(app.TabGroup3);
            app.HomeFolderTab.Title = 'Home Folder';
            app.DirectoryPathLabel = uilabel(app.HomeFolderTab);
            app.DirectoryPathLabel.Text = 'Directory Path';
            app.Home = uieditfield(app.HomeFolderTab, 'text');
            app.Home.Value = 'Volume/User/.../cytosolQuant';
            app.ExperimentsLabel = uilabel(app.cytosolQuant2022UIFigure);
            app.ExperimentsLabel.Text = 'Experiments';
            app.ProcessingLabel = uilabel(app.cytosolQuant2022UIFigure);
            app.ProcessingLabel.Text = 'Processing';
            % Show the figure after all components are created
            app.cytosolQuant2022UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cytosolQuant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cytosolQuant2022UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cytosolQuant2022UIFigure)
        end
    end
end